classdef prac3_pruebaB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SealesTab                       matlab.ui.container.Tab
        omegaEditField                  matlab.ui.control.NumericEditField
        omegaEditFieldLabel             matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        HastaEditField                  matlab.ui.control.NumericEditField
        HastaEditFieldLabel             matlab.ui.control.Label
        DesdeEditField                  matlab.ui.control.NumericEditField
        DesdeEditFieldLabel             matlab.ui.control.Label
        DesplazamientoEditField         matlab.ui.control.NumericEditField
        DesplazamientoEditFieldLabel    matlab.ui.control.Label
        PeriodosEditField               matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel          matlab.ui.control.Label
        FrecuenciadeMuestreoEditField   matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        FrecuenciaFundamentalEditField  matlab.ui.control.NumericEditField
        FrecuenciaFundamentalEditFieldLabel  matlab.ui.control.Label
        AmplitudEditField               matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel          matlab.ui.control.Label
        LimpiarButton                   matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        SealDropDown                    matlab.ui.control.DropDown
        SealDropDownLabel               matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        alpha = 1;
        omega = 1;
      
    end
    
    methods (Access = public)
        
         function [senial, par, impar] = makeSenial(app, argumento, tipo)
            switch tipo
                case "cuadrada"
                    senial = square(argumento);
                    senial_not = square(- argumento);
                case "sierra"
                    senial = sawtooth(argumento,.5);
                    senial_not = sawtooth(- argumento,.5);
                case "senoidal"
                    senial = sin(argumento);
                    senial_not = sin(- argumento);
                case "exp creciente"
                    senial = exp(app.alpha*argumento);
                    senial_not = exp(- app.alpha*argumento);
                case "exp decreciente"
                    senial = exp(-app.alpha*argumento);
                    senial_not = exp(app.alpha*argumento);
                case "escalon"
                    senial = heaviside(argumento);
                    senial_not = heaviside(- argumento);
                case "impulso"
                    senial = generarImpulsoUnitario(app, argumento);
                    senial_not = generarImpulsoUnitario(app, - argumento);
                case "rampa"
                    senial = generarRampa(app, argumento);
                    senial_not = generarRampa(app, - argumento);
                case "triangulo"
                    senial = generarTriangulo(app, argumento, 10);
                    senial_not = generarTriangulo(app, - argumento, 10);
                case "senoidal com"
                    senial = exp(app.alpha * argumento).*cos(app.omega * argumento);
                    senial_not = exp(-app.alpha * argumento).*cos(-app.omega * argumento);
                case "rectangulo"
                    senial = generarRectangulo(app, argumento, 10);
                    senial_not = generarRectangulo(app, - argumento, 10);
                case "exp compleja"
                    senial = generarExponencialCompleja(app, argumento);
                    senial_not = generarExponencialCompleja(app, - argumento);
                otherwise
                    cla(app.UIAxes)
                    %cla(app.UIAxes_2)
                    %cla(app.UIAxes_3)
            end
            par = (senial + senial_not)/2;
            impar = (senial - senial_not)/2;
        end
        
        function graficar3(app, modo, A, x, senial, par, impar)
            if modo == 0
                plot(app.UIAxes,x,A*senial)
                plot(app.UIAxes_2,x,A*par)
                plot(app.UIAxes_3,x,A*impar)
            elseif modo == 1
                stem(app.UIAxes,x,A*senial)
                stem(app.UIAxes_2,x,A*par)
                stem(app.UIAxes_3,x,A*impar)
            end

        end
        
        function impulso = generarImpulsoUnitario(app, tiempo)
            % Inicializar la señal de impulso
            impulso = zeros(size(tiempo));
            
            % Definir el valor del impulso unitario
            valorImpulso = 1;
            
            % Encontrar el índice en el tiempo más cercano a t = 0
            indiceT0 = find(tiempo == 0, 1);
            
            % Asignar el valor del impulso unitario en t = 0
            impulso(indiceT0) = valorImpulso;
        end
        
        function rampa = generarRampa(app,tiempo)
            % Inicializar la señal de rampa
            rampa = zeros(size(tiempo));
            
            % Encontrar el índice en el tiempo más cercano a t = 0
            indiceT0 = find(tiempo == 0, 1);
            
            % Asignar la pendiente de la rampa
            pendiente = 1;
            
            % Calcular la señal de rampa
            rampa(indiceT0:end) = pendiente * tiempo(indiceT0:end);
        end
        
        function triangulo = generarTriangulo(app, tiempo, periodo)
            % Inicializar la señal de triángulo
            triangulo = zeros(size(tiempo));
            
            % Encontrar el índice en el tiempo más cercano a t = 0
            indiceT0 = find(tiempo == 0, 1);
            
            % Definir la amplitud del triángulo
            amplitud = 1;
            
            % Calcular la señal de triángulo
            for i = indiceT0:length(tiempo)
                t = tiempo(i);
                triangulo(i) = amplitud * (1 - 2 * abs(mod(t / periodo, 1) - 0.5));
            end
        end
        
        function rectangulo = generarRectangulo(app, tiempo, ancho)
            % Inicializar la señal de rectángulo
            rectangulo = zeros(size(tiempo));
            
            % Encontrar el índice en el tiempo más cercano a t = 0
            indiceT0 = find(tiempo == 0, 1);
            
            % Definir la amplitud del rectángulo
            amplitud = 1;
            
            % Calcular la señal de rectángulo
            for i = indiceT0:length(tiempo)
                t = tiempo(i);
                if abs(t) <= ancho / 2
                    rectangulo(i) = amplitud;
                end
            end
        end

        function exponencialCompleja = generarExponencialCompleja(app, tiempo)
            % Inicializar la señal de exponencial compleja
            exponencialCompleja = zeros(size(tiempo));
            
            % Calcular la señal de exponencial compleja
            for i = 1:length(tiempo)
                t = tiempo(i);
                exponencialCompleja(i) = exp(1i * (2 * pi * t));
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AmplitudEditField, DesdeEditField, 
        % ...and 10 other components
        function GraficarButtonPushed(app, event)
            app.UIAxes.Title.Color = "w";
            app.UIAxes_2.Title.Color = "w";
            app.UIAxes_3.Title.Color = "w";

            A = app.AmplitudEditField.Value;
            Fs = app.FrecuenciadeMuestreoEditField.Value;
            f = app.FrecuenciaFundamentalEditField.Value;
            T = app.PeriodosEditField.Value;
            Lim_inf = app.DesdeEditField.Value;
            Lim_sup = app.HastaEditField.Value;
            xlim(app.UIAxes,[Lim_inf, T*Lim_sup])
            t = Lim_inf:1/Fs:(T* Lim_sup);
            n = Lim_inf:(1/Fs)*10:(T*Lim_sup);
            D = app.DesplazamientoEditField.Value;
            % n = 0:T;
            f2 = f/80;
            %n = 0:(3*(1/f2));
            phi=pi/2;
            r=1.5;
            app.alpha = app.alphaEditField.Value;
            app.omega = app.omegaEditField.Value;
     
            opcion = app.SealDropDown.Value;
            if(opcion == "Seleccione una opcion")
                cla(app.UIAxes)
                app.UIAxes.Title.String="";
            elseif(opcion == "Cuadrada")
                app.UIAxes.Title.String="Cuadrada";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "cuadrada");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "cuadrada");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Diente de Sierra")
                app.UIAxes.Title.String="Diente de Sierra";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "sierra");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "sierra");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Senoidal")
                app.UIAxes.Title.String="Senoidal";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "senoidal");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "senoidal");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Exponencial creciente")
                app.UIAxes.Title.String="Exponencial Creciente";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";

                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "exp creciente");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "exp creciente");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Exponencial decreciente")
                app.UIAxes.Title.String="Exponencial Decreciente";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "exp decreciente");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "exp decreciente");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Escalon")
                app.UIAxes.Title.String="Escalon";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                n2=0:10*f;
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "escalon");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "escalon");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Impulso")
                
                app.UIAxes.Title.String="Impulso";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                if(app.Switch.Value == "Discreto")
%                     stem(app.UIAxes,A.*[zeros(1,3),ones(1,1),zeros(1,2)])
                    
                    [senial, par, impar] = makeSenial(app, f*n + D, "impulso");
                    graficar3(app, 1, A, n, senial, par, impar)
                
                else
                    warndlg("Esta funcion no esta disponible en representacion Continua")
                    app.Switch.Value = 'Discreto';
                    cla(app.UIAxes)
                end
            elseif(opcion == "Rampa")
                app.UIAxes.Title.String="Rampa";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                t11=0:1/Fs:5*f;
                n11=0:5*f;
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "rampa");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "rampa");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            
            elseif(opcion == "Triangulo")
                app.UIAxes.Title.String="Triangulo";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                t13= linspace(-1, 1, 100);
                width = 10; % Ancho de la función triangular
                tri= tripuls(t13, width);
                tri2= tripuls(t13, 2);
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "triangulo");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "triangulo");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Senoidal Compleja")
                app.UIAxes.Title.String="Senoidal Compleja";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                y = exp(-t) .* cos(2*pi*t);
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "senoidal com");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "senoidal com");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Rectangulo")
                app.UIAxes.Title.String="Rectangulo / Pulso Unitario";
                app.UIAxes.XLabel.String="Tiempo";
                app.UIAxes.YLabel.String="f(t)";
                t12 = linspace(-1, 1, 100);
                u = abs(t12) < 0.5;
                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "rectangulo");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "rectangulo");
                    graficar3(app, 1, A, n, senial, par, impar)
                end
            elseif(opcion == "Exponencial compleja")
                app.UIAxes.Title.String="Exponencial Compleja";
                cla(app.UIAxes)
                % 
                % tc = -1.5*pi:1/Fs:1.5*pi;
                % nc = -1.5*pi:0.1:1.5*pi;
                % funcioon = A*exp(1j.*(f* 2.*tc+(pi/4)));
                % xRE = real(funcioon);
                % xIM = imag(funcioon);
                % xMAG = abs(funcioon);
                % xARG = atan(imag(funcioon)./real(funcioon));
                % hold(app.UIAxes,"on")

                if(app.Switch.Value == "Continuo")
                    [senial, par, impar] = makeSenial(app, f*t+D, "exp compleja");
                    graficar3(app, 0, A, t, senial, par, impar)
                else
                    [senial, par, impar] = makeSenial(app, f*n+D, "exp compleja");
                    graficar3(app, 1, A, n, senial, par, impar)
                end

                % if(app.Switch.Value == "Continuo")
                %     plot(app.UIAxes,xRE,"-r")
                % 
                %     plot(app.UIAxes,xIM,"-b")
                %     plot(app.UIAxes,xMAG,"-m")
                %     plot(app.UIAxes,xARG,"-y")
                % 
                % else
                %     funcioon = A*exp(1j.*(f* 2.*nc+(pi/4)));
                %     xRE = real(funcioon);
                %     xIM = imag(funcioon);
                %     xMAG = abs(funcioon);
                %     xARG = atan(imag(funcioon)./real(funcioon));
                %     stem(app.UIAxes,xRE,"-r")
                % 
                %     stem(app.UIAxes,xIM,"-b")
                %     stem(app.UIAxes,xMAG,"-m")
                %     stem(app.UIAxes,xARG,"-y")
                % 
                % end
                % hold(app.UIAxes,"off")


            end

        end

        % Callback function
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes)
            app.UIAxes.YLim=[-1,1];
            cla(app.UIAxes_2)
            app.UIAxes_2.YLim=[-1,1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SealesTab = uitab(app.TabGroup);
            app.SealesTab.SizeChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.SealesTab.Title = 'Señales';
            app.UIAxes = uiaxes(app.SealesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.SealesTab);
            xlabel(app.UIAxes_2, 'i')
            ylabel(app.UIAxes_2, 'k')
            colormap(app.UIAxes_2, 'gray')
            app.UIAxes_3 = uiaxes(app.SealesTab);
            xlabel(app.UIAxes_3, 'i')
            ylabel(app.UIAxes_3, 'k')
            colormap(app.UIAxes_3, 'gray')
            app.SealDropDownLabel = uilabel(app.SealesTab);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.SealesTab);
            app.SealDropDown.Items = {'Seleccione una opcion', 'Cuadrada', 'Diente de Sierra', 'Senoidal', 'Senoidal Compleja', 'Exponencial creciente', 'Exponencial decreciente', 'Escalon', 'Impulso', 'Rampa', 'Rectangulo', 'Triangulo', 'Exponencial compleja'};
            app.SealDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.SealDropDown.Value = 'Seleccione una opcion';
            app.Label = uilabel(app.SealesTab);
            app.Label.Text = '';
            app.Switch = uiswitch(app.SealesTab, 'slider');
            app.Switch.Items = {'Discreto', 'Continuo'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.Switch.Value = 'Discreto';
            app.LimpiarButton = uibutton(app.SealesTab, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.AmplitudEditFieldLabel = uilabel(app.SealesTab);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.SealesTab, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.AmplitudEditField.Value = 1;
            app.FrecuenciaFundamentalEditFieldLabel = uilabel(app.SealesTab);
            app.FrecuenciaFundamentalEditFieldLabel.Text = 'Frecuencia Fundamental';
            app.FrecuenciaFundamentalEditField = uieditfield(app.SealesTab, 'numeric');
            app.FrecuenciaFundamentalEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.FrecuenciaFundamentalEditField.Value = 1;
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.SealesTab);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.FrecuenciadeMuestreoEditField = uieditfield(app.SealesTab, 'numeric');
            app.FrecuenciadeMuestreoEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.FrecuenciadeMuestreoEditField.Value = 100;
            app.PeriodosEditFieldLabel = uilabel(app.SealesTab);
            app.PeriodosEditFieldLabel.Text = '# Periodos';
            app.PeriodosEditField = uieditfield(app.SealesTab, 'numeric');
            app.PeriodosEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.PeriodosEditField.Value = 1;
            app.DesplazamientoEditFieldLabel = uilabel(app.SealesTab);
            app.DesplazamientoEditFieldLabel.Text = 'Desplazamiento';
            app.DesplazamientoEditField = uieditfield(app.SealesTab, 'numeric');
            app.DesplazamientoEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.DesdeEditFieldLabel = uilabel(app.SealesTab);
            app.DesdeEditFieldLabel.Text = 'Desde...';
            app.DesdeEditField = uieditfield(app.SealesTab, 'numeric');
            app.DesdeEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.DesdeEditField.Value = -1;
            app.HastaEditFieldLabel = uilabel(app.SealesTab);
            app.HastaEditFieldLabel.Text = 'Hasta...';
            app.HastaEditField = uieditfield(app.SealesTab, 'numeric');
            app.HastaEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.HastaEditField.Value = 1;
            app.alphaEditFieldLabel = uilabel(app.SealesTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.SealesTab, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.alphaEditField.Value = 1;
            app.omegaEditFieldLabel = uilabel(app.SealesTab);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.SealesTab, 'numeric');
            app.omegaEditField.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.omegaEditField.Value = 9;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac3_pruebaB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end